classdef tankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DeneyZamannGirerekTab          matlab.ui.container.Tab
        DeneyTarihiLabel               matlab.ui.control.Label
        DeneySaatiLabel                matlab.ui.control.Label
        EditFieldTarih                 matlab.ui.control.EditField
        EditFieldSaat                  matlab.ui.control.EditField
        DeneyTrSeerekTab               matlab.ui.container.Tab
        TurLPMPanel                    matlab.ui.container.ButtonGroup
        TurLPM40Radio                  matlab.ui.control.RadioButton
        TurLPM80Radio                  matlab.ui.control.RadioButton
        TurLPM120Radio                 matlab.ui.control.RadioButton
        TurCapPanel                    matlab.ui.container.ButtonGroup
        TurCap6mmRadio                 matlab.ui.control.RadioButton
        TurCap8mmRadio                 matlab.ui.control.RadioButton
        TurCap10mmRadio                matlab.ui.control.RadioButton
        TurCap12mmRadio                matlab.ui.control.RadioButton
        TurDelikPanel                  matlab.ui.container.Panel
        TurDelik1CheckBox              matlab.ui.control.CheckBox
        TurDelik2CheckBox              matlab.ui.control.CheckBox
        TurDelik4CheckBox              matlab.ui.control.CheckBox
        TurDelik3CheckBox              matlab.ui.control.CheckBox
        TurDelik7CheckBox              matlab.ui.control.CheckBox
        TurDelik6CheckBox              matlab.ui.control.CheckBox
        TurDelik5CheckBox              matlab.ui.control.CheckBox
        TurCokluDelikPanel             matlab.ui.container.Panel
        TurCokluDelik12CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik13CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik15CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik14CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik17CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik16CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik24CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik23CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik34CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik45CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik37CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik36CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik35CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik57CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik56CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik47CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik46CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik67CheckBox        matlab.ui.control.CheckBox
        TurCokluDelikPanel_2           matlab.ui.container.Panel
        TurCokluDelik34CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik45CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik37CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik36CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik35CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik57CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik56CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik47CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik46CheckBox_2      matlab.ui.control.CheckBox
        ExcelSatrAralnBelirleyerekTab  matlab.ui.container.Tab
        ExcelSatrBalangcLabel          matlab.ui.control.Label
        ExcelSatrSonuLabel             matlab.ui.control.Label
        EditFieldBitis                 matlab.ui.control.NumericEditField
        EditFieldBaslangic             matlab.ui.control.NumericEditField
        EditFieldDosyaIsmi             matlab.ui.control.EditField
        ExcelDosyasnnismiLabel         matlab.ui.control.Label
        xlsxLabel                      matlab.ui.control.Label
        oncekiGrafikCheckBox           matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
        VeriPanel                      matlab.ui.container.Panel
        VeriDebiCheckBox               matlab.ui.control.CheckBox
        VeriBasinc2CheckBox            matlab.ui.control.CheckBox
        VeriBasinc1CheckBox            matlab.ui.control.CheckBox
        DeerGstericiPanel              matlab.ui.container.Panel
        VeriSetiLabel                  matlab.ui.control.Label
        VeriSetiDropDown               matlab.ui.control.DropDown
        SaniyeEditFieldLabel           matlab.ui.control.Label
        SaniyeEditField                matlab.ui.control.NumericEditField
        VeriyiGsterButton              matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        StandartSapmalarLabel          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        startOfDatalogger = 0; % when data logger is opened
        
        startOfFilling = 0; % when filling tank is started
        
        finishOfFilling = 0; % when filling tank is finished
        
        time = string([]); % clock values of row array of datalogger
        
        flowmeter = []; % flowmeter values of row array of datalogger

        titleValue = ''; % graph title
        
        legendValue = string([]); % legend array for graph
        
        tank_1 = []; % all tank sensor values in specific row range (flowmeter and pressures sensors)
        
        t; % time array used in graph
        
        plots = gobjects;

        dataset = string([]);
        
        standard_deviation = string([]);
        
    end
    
    methods (Access = private)
        
        function variables(app, tarih)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %preparing needed variables
                [sizeOfRow, ~] = size(xlsread(append(tarih, '.xlsx'), 'Sayfa1', 'D:D'));
                %sizeOfColumn: column number/~: Row number
                %D:D -> Reading all D column
                
                %to read all clock in clock time column (B column)
                [~,app.time] = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('B2:B', string(sizeOfRow)));
                %[~,time] -> to read column as string
                
                %to read all flowmeter values in flowmeter column (D column)
                app.flowmeter = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('D2:D', string(sizeOfRow)));
                
        end
        
        function rowsValue(app, saat)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when data logger is opened
                for i = 1:length(app.time)
                    if strcmp(app.time{i}, saat)
                    app.startOfDatalogger = i;
                    break
                    end
                end
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is started
                for k = app.startOfDatalogger:length(app.time)
                    if app.flowmeter(k) > 1
                    app.startOfFilling = k - 20;
                    break
                    end
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is finished
                for j = app.startOfFilling + 70:length(app.time)
                    a  = true;
                    
                    for k=1:110
                        if(length(app.flowmeter)>j + 2*k)
                            if(app.flowmeter(j + 2*k) > 0.55 )
                                a = false;
                            end
                        else
                            break
                        end
                    end
                    if a
                    app.finishOfFilling = j + 10;
                    break
                    end
                end
                
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % getting X and Y array for plotting graph
        function initializingGraph(app, dosyaIsmi, baslangic, bitis)
             app.tank_1 = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('D', string(baslangic),':F', string(bitis)));           
             app.t=0.05:0.05:(bitis-baslangic +1)*0.05;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 1
        function plottingBasinc1(app, deney)
            if(app.VeriBasinc1CheckBox.Value)
                hold(app.UIAxes,'on');
                app.titleValue = 'Basınç 1';
                app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Basınç 1');
                app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 2), 'Color' , [rand rand rand]);
                app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 2)));
                stdev(app, app.tank_1(:, 2));
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 2
        function plottingBasinc2(app, deney)
              if(app.VeriBasinc2CheckBox.Value)
                    hold(app.UIAxes,'on');
                    if(strcmp(app.titleValue, 'Basınç 1'))
                        app.titleValue = append(app.titleValue, ', Basınç 2');
                    else
                        app.titleValue = 'Basınç 2';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Basınç 2');
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 3), 'Color' , [rand rand rand]);
                    app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 3)));
                    stdev(app, app.tank_1(:, 3));
              end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting debi
        function plottingDebi(app, deney)
                if(app.VeriDebiCheckBox.Value)
                    hold(app.UIAxes,'on');
                    if(contains(app.titleValue, 'Basınç'))
                        app.titleValue = append(app.titleValue, ', Debi');
                    else
                        app.titleValue = 'Debi';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Debi');
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 1), 'Color' , [rand rand rand]);
                    app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 1)));
                    stdev(app, app.tank_1(:, 1))
                end

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting labelling graph
        function graphLabels(app)
            title(app.UIAxes, app.titleValue);
            app.VeriSetiDropDown.Items = app.legendValue;
            xlabel(app.UIAxes, 'Zaman (saniye)');
            ylabel(app.UIAxes, app.titleValue);
            legend(app.UIAxes, app.legendValue, 'Location','northeastoutside');
            app.ListBox.Items = app.standard_deviation;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % resetting global variable for repeating
        function resettingGlobalVariables(app)             
        app.startOfFilling = 0; % when filling tank is started   
        app.finishOfFilling = 0; % when filling tank is finished
        app.time = string([]);       
        app.flowmeter = [];
        app.titleValue = ''; % Description  
        app.legendValue = string([]);    
        app.tank_1 = []; % Description
        end
        
        function stdev(app, data_basinc)
            sum = 0;
            for i = 1:length(data_basinc)
                sum = sum + data_basinc(i);
            end
            mean = sum/length(data_basinc);
            sum_squared = 0;
            for j = 1:length(data_basinc)
                sum_squared = sum_squared + (data_basinc(j) - mean).^2;
            end
            app.standard_deviation(length(app.standard_deviation)+1) = append(string(length(app.standard_deviation)+1), '------   ', string(sqrt(sum_squared/length(data_basinc))));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.VeriDebiCheckBox.Value || app.VeriBasinc1CheckBox.Value || app.VeriBasinc2CheckBox.Value)  %checking whether any data checkbox is selected or not       
                
                switch app.TabGroup.SelectedTab.Title
                    case 'Deney Türü Seçerek'
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables
                        resettingGlobalVariables(app);
                        dim = split(app.TurCapPanel.SelectedObject.Text);
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %initializing interface and data array
                        checkboxArray = {app.TurDelik1CheckBox.Value, app.TurDelik2CheckBox.Value, app.TurDelik3CheckBox.Value, app.TurDelik4CheckBox.Value, app.TurDelik5CheckBox.Value, app.TurDelik6CheckBox.Value, app.TurDelik7CheckBox.Value};
                        cokluCheckboxArray = {app.TurCokluDelik12CheckBox.Value, app.TurCokluDelik13CheckBox.Value, app.TurCokluDelik14CheckBox.Value, app.TurCokluDelik15CheckBox.Value, app.TurCokluDelik16CheckBox.Value, app.TurCokluDelik17CheckBox.Value, app.TurCokluDelik23CheckBox.Value, app.TurCokluDelik24CheckBox.Value, app.TurCokluDelik25CheckBox.Value, app.TurCokluDelik26CheckBox.Value, app.TurCokluDelik27CheckBox.Value, app.TurCokluDelik34CheckBox.Value, app.TurCokluDelik35CheckBox.Value, app.TurCokluDelik36CheckBox.Value, app.TurCokluDelik37CheckBox.Value, app.TurCokluDelik45CheckBox.Value, app.TurCokluDelik46CheckBox.Value, app.TurCokluDelik47CheckBox.Value, app.TurCokluDelik56CheckBox.Value, app.TurCokluDelik57CheckBox.Value, app.TurCokluDelik67CheckBox.Value};                   
                        cokluCheckboxNameArray = {app.TurCokluDelik12CheckBox.Text, app.TurCokluDelik13CheckBox.Text, app.TurCokluDelik14CheckBox.Text, app.TurCokluDelik15CheckBox.Text, app.TurCokluDelik16CheckBox.Text, app.TurCokluDelik17CheckBox.Text, app.TurCokluDelik23CheckBox.Text, app.TurCokluDelik24CheckBox.Text, app.TurCokluDelik25CheckBox.Text, app.TurCokluDelik26CheckBox.Text, app.TurCokluDelik27CheckBox.Text, app.TurCokluDelik34CheckBox.Text, app.TurCokluDelik35CheckBox.Text, app.TurCokluDelik36CheckBox.Text, app.TurCokluDelik37CheckBox.Text, app.TurCokluDelik45CheckBox.Text, app.TurCokluDelik46CheckBox.Text, app.TurCokluDelik47CheckBox.Text, app.TurCokluDelik56CheckBox.Text, app.TurCokluDelik57CheckBox.Text, app.TurCokluDelik67CheckBox.Text};                   
                        delikler = string([]);
                        cokluDelikler = string([]);
                        
                        for i = 1:21
                            if(strcmp(string(cokluCheckboxArray(i)), 'true'))
                                cokluDelikler(length(cokluDelikler)+1) = append('hole ', cokluCheckboxNameArray(i));
                            end
                        end
                        
                        for i = 1:7
                            if(strcmp(string(checkboxArray(i)), 'true'))
                                delikler(length(delikler)+1) = append('hole ', string(i));
                            end
                        end
                        
            
                        [~, search] = xlsread('matlabApp.xlsx', 'Sayfa1', 'A:A');
                        [~, tarih] = xlsread('matlabApp.xlsx', 'Sayfa1', 'C:C');
                        [~, saat] = xlsread('matlabApp.xlsx', 'Sayfa1', 'B:B');
                        
                        delikSecenekleri = cat(2,delikler, cokluDelikler);
                        if(app.oncekiGrafikCheckBox.Value)
                            cla(app.UIAxes);
                            app.plots = gobjects;
                            app.dataset = string([]);
                            app.standard_deviation = string([]);
                        end
                        
                        for h = 1:length(delikSecenekleri)
                        
                            satir = 0;


                            for i = 1:length(search)
                                if(h>length(delikler))
                                    if(~isempty(cokluDelikler))
                                        if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  append('diameter ', string(dim(1)))))
                                        satir = i;
                                        break
                                        end
                                    end
                                else
                                    if(~isempty(delikler))
                                        if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  append('diameter ', string(dim(1)))))
                                        satir = i;
                                        break
                                        end
                                    end
                                end
                            end
                        
          
                            variables(app, string(tarih(satir)));      
                            
                            rowsValue(app, string(saat(satir)));
        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %to plot pressure graph
                            initializingGraph(app, string(tarih(satir)), app.startOfFilling, app.finishOfFilling);  
                            plottingBasinc1(app, append(search(satir), ' - '));                
                            plottingBasinc2(app, append(search(satir), ' - '));                
                            plottingDebi(app, append(search(satir), ' - '));                   
                            graphLabels(app); 
        
                        end
                    case 'Excel Satır Aralığını Belirleyerek'
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot pressure graph
                        if(app.oncekiGrafikCheckBox.Value)
                            cla(app.UIAxes);  % resetting graph before next button push to plot new graph
                            app.plots = gobjects;
                            app.dataset = string([]);
                            app.standard_deviation = string([]);
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        resettingGlobalVariables(app);
                        initializingGraph(app, app.EditFieldDosyaIsmi.Value, app.EditFieldBaslangic.Value, app.EditFieldBitis.Value);           
                        plottingBasinc1(app, '');           
                        plottingBasinc2(app, '');          
                        plottingDebi(app, '');      
                        graphLabels(app);
                    otherwise
                        if(app.oncekiGrafikCheckBox.Value)
                            cla(app.UIAxes);   % resetting graph before next button push to plot new graph
                            app.plots = gobjects;
                            app.dataset = string([]);
                            app.standard_deviation = string([]);
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables and data before plotting               
                        resettingGlobalVariables(app);
                        variables(app, app.EditFieldTarih.Value);      
                        rowsValue(app, app.EditFieldSaat.Value);
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        initializingGraph(app, app.EditFieldTarih.Value, app.startOfFilling, app.finishOfFilling);  
                        plottingBasinc1(app, '');                
                        plottingBasinc2(app, '');                
                        plottingDebi(app, '');                   
                        graphLabels(app);
                end
                
                    
            else
                 warndlg('Lütfen En Az Bir Veri Seçiniz.','Veri Seçili Değil');
            end
        end

        % Button pushed function: VeriyiGsterButton
        function VeriyiGsterButtonPushed(app, event)
            arrayhold = jsondecode(app.dataset(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))));
            datatip(app.plots(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))+1) , app.SaniyeEditField.Value, arrayhold(round(20*app.SaniyeEditField.Value)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeneyZamannGirerekTab = uitab(app.TabGroup);
            app.DeneyZamannGirerekTab.Title = 'Deney Zamanını Girerek';
            app.DeneyTarihiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneyTarihiLabel.Text = 'Deney Tarihi';
            app.DeneySaatiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneySaatiLabel.Text = 'Deney Saati';
            app.EditFieldTarih = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.EditFieldSaat = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.DeneyTrSeerekTab = uitab(app.TabGroup);
            app.DeneyTrSeerekTab.Title = 'Deney Türü Seçerek';
            app.TurLPMPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurLPMPanel.Title = 'LPM Seçiniz';
            app.TurLPM40Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM40Radio.Text = '40 lpm';
            app.TurLPM40Radio.Value = true;
            app.TurLPM80Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM80Radio.Text = '80 lpm';
            app.TurLPM120Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM120Radio.Text = '120 lpm';
            app.TurCapPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurCapPanel.Title = 'Çap Seçiniz';
            app.TurCap6mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap6mmRadio.Text = '6 mm';
            app.TurCap6mmRadio.Value = true;
            app.TurCap8mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap8mmRadio.Text = '8 mm';
            app.TurCap10mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap10mmRadio.Text = '10 mm';
            app.TurCap12mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap12mmRadio.Text = '12 mm';
            app.TurDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurDelikPanel.Title = 'Delik Seçiniz';
            app.TurDelik1CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik1CheckBox.Text = '1. Delik';
            app.TurDelik2CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik2CheckBox.Text = '2. Delik';
            app.TurDelik4CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik4CheckBox.Text = '4. Delik';
            app.TurDelik3CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik3CheckBox.Text = '3. Delik';
            app.TurDelik7CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik7CheckBox.Text = '7.  Delik';
            app.TurDelik6CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik6CheckBox.Text = '6. Delik';
            app.TurDelik5CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik5CheckBox.Text = '5. Delik';
            app.TurCokluDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurCokluDelikPanel.Title = 'İkili Delik Seçiniz';
            app.TurCokluDelik12CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik12CheckBox.Text = '1 + 2';
            app.TurCokluDelik13CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik13CheckBox.Text = '1 + 3';
            app.TurCokluDelik15CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik15CheckBox.Text = '1 + 5';
            app.TurCokluDelik14CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik14CheckBox.Text = '1 + 4';
            app.TurCokluDelik17CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik17CheckBox.Text = '1 + 7';
            app.TurCokluDelik16CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik16CheckBox.Text = '1 + 6';
            app.TurCokluDelik24CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik24CheckBox.Text = '2 + 4';
            app.TurCokluDelik23CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik23CheckBox.Text = '2 + 3';
            app.TurCokluDelik34CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik34CheckBox.Text = '3 + 4';
            app.TurCokluDelik27CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik27CheckBox.Text = '2 + 7';
            app.TurCokluDelik26CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik26CheckBox.Text = '2 + 6';
            app.TurCokluDelik25CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik25CheckBox.Text = '2 + 5';
            app.TurCokluDelik45CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik45CheckBox.Text = '4 + 5';
            app.TurCokluDelik37CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik37CheckBox.Text = '3 + 7';
            app.TurCokluDelik36CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik36CheckBox.Text = '3 + 6';
            app.TurCokluDelik35CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik35CheckBox.Text = '3 + 5';
            app.TurCokluDelik57CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik57CheckBox.Text = '5 + 7';
            app.TurCokluDelik56CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik56CheckBox.Text = '5 + 6';
            app.TurCokluDelik47CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik47CheckBox.Text = '4 + 7';
            app.TurCokluDelik46CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik46CheckBox.Text = '4 + 6';
            app.TurCokluDelik67CheckBox = uicheckbox(app.TurCokluDelikPanel);
            app.TurCokluDelik67CheckBox.Text = '6 + 7';
            app.TurCokluDelikPanel_2 = uipanel(app.DeneyTrSeerekTab);
            app.TurCokluDelikPanel_2.Title = 'İkili Delik Seçiniz (Boğaza Bağlı)';
            app.TurCokluDelik34CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik34CheckBox_2.Text = '3 + 4';
            app.TurCokluDelik27CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik27CheckBox_2.Text = '2 + 7';
            app.TurCokluDelik26CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik26CheckBox_2.Text = '2 + 6';
            app.TurCokluDelik25CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik25CheckBox_2.Text = '2 + 5';
            app.TurCokluDelik45CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik45CheckBox_2.Text = '4 + 5';
            app.TurCokluDelik37CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik37CheckBox_2.Text = '3 + 7';
            app.TurCokluDelik36CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik36CheckBox_2.Text = '3 + 6';
            app.TurCokluDelik35CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik35CheckBox_2.Text = '3 + 5';
            app.TurCokluDelik57CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik57CheckBox_2.Text = '5 + 7';
            app.TurCokluDelik56CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik56CheckBox_2.Text = '5 + 6';
            app.TurCokluDelik47CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik47CheckBox_2.Text = '4 + 7';
            app.TurCokluDelik46CheckBox_2 = uicheckbox(app.TurCokluDelikPanel_2);
            app.TurCokluDelik46CheckBox_2.Text = '4 + 6';
            app.ExcelSatrAralnBelirleyerekTab = uitab(app.TabGroup);
            app.ExcelSatrAralnBelirleyerekTab.Title = 'Excel Satır Aralığını Belirleyerek';
            app.ExcelSatrBalangcLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrBalangcLabel.Text = 'Excel Satır Başlangıcı';
            app.ExcelSatrSonuLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrSonuLabel.Text = 'Excel Satır Sonu';
            app.EditFieldBitis = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldBaslangic = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldDosyaIsmi = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'text');
            app.ExcelDosyasnnismiLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelDosyasnnismiLabel.Text = 'Excel Dosyasının ismi';
            app.xlsxLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.xlsxLabel.Text = '.xlsx';
            app.oncekiGrafikCheckBox = uicheckbox(app.UIFigure);
            app.oncekiGrafikCheckBox.Text = 'Önceki grafiği sil';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Grafiği Göster';
            app.VeriPanel = uipanel(app.UIFigure);
            app.VeriPanel.Title = 'Verileri Seçiniz';
            app.VeriDebiCheckBox = uicheckbox(app.VeriPanel);
            app.VeriDebiCheckBox.Text = 'Debi';
            app.VeriBasinc2CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc2CheckBox.Text = 'Basınç 2';
            app.VeriBasinc1CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc1CheckBox.Text = 'Basınç 1';
            app.DeerGstericiPanel = uipanel(app.UIFigure);
            app.DeerGstericiPanel.Title = 'Değer Gösterici';
            app.VeriSetiLabel = uilabel(app.DeerGstericiPanel);
            app.VeriSetiLabel.Text = 'Veri Seti:';
            app.VeriSetiDropDown = uidropdown(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel = uilabel(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel.Text = 'Saniye:';
            app.SaniyeEditField = uieditfield(app.DeerGstericiPanel, 'numeric');
            app.VeriyiGsterButton = uibutton(app.DeerGstericiPanel, 'push');
            app.VeriyiGsterButton.ButtonPushedFcn = createCallbackFcn(app, @VeriyiGsterButtonPushed, true);
            app.VeriyiGsterButton.Text = 'Veriyi Göster';
            app.ListBox = uilistbox(app.UIFigure);
            app.StandartSapmalarLabel = uilabel(app.UIFigure);
            app.StandartSapmalarLabel.Text = 'Standart Sapmalar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end